classdef MechanismSynth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SALARMechanismsynthesizerUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        FileLoadMenu                    matlab.ui.container.Menu
        FileSaveMenu                    matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ProblemSetupPanel               matlab.ui.container.Panel
        PointsinthereferencepathNLabel  matlab.ui.control.Label
        SetupNpointsEditField           matlab.ui.control.NumericEditField
        TypeDropDownLabel               matlab.ui.control.Label
        SetupTypeDropDown               matlab.ui.control.DropDown
        SetupFixtoOriginCheckBox        matlab.ui.control.CheckBox
        SetupClosedPathCheckBox         matlab.ui.control.CheckBox
        SetupResetButton                matlab.ui.control.Button
        SetupPreviewButton              matlab.ui.control.Button
        SetupDesiredTable               matlab.ui.control.Table
        OptimizationSetupPanel          matlab.ui.container.Panel
        SalLogoImage                    matlab.ui.control.Image
        ARMLogoImage                    matlab.ui.control.Image
        OptimTabGroup                   matlab.ui.container.TabGroup
        OptimFMinConTab                 matlab.ui.container.Tab
        OptimUseFMinConCheckBox         matlab.ui.control.CheckBox
        OptimFMinConLabel               matlab.ui.control.Label
        OptimFMCParametersPanel         matlab.ui.container.Panel
        OptimFMCStartfromCheckBox       matlab.ui.control.CheckBox
        RandomrestartsLabel             matlab.ui.control.Label
        OptimFMCNRestartsEditField      matlab.ui.control.NumericEditField
        OptimTLBOTab                    matlab.ui.container.Tab
        OptimUseTLBOCheckBox            matlab.ui.control.CheckBox
        OptimTLBOLabel                  matlab.ui.control.Label
        OptimTLBOParametersPanel        matlab.ui.container.Panel
        PopulationsizeLabel             matlab.ui.control.Label
        OptimTLBOPopSEditField          matlab.ui.control.NumericEditField
        NumberofcyclesLabel             matlab.ui.control.Label
        OptimTLBONCycEditField          matlab.ui.control.NumericEditField
        RestartsLabel                   matlab.ui.control.Label
        OptimTLBOFullRestartsEditField  matlab.ui.control.NumericEditField
        OptimMUMSATab                   matlab.ui.container.Tab
        OptimUseMUMSACheckBox           matlab.ui.control.CheckBox
        OptimMUMSALabel                 matlab.ui.control.Label
        OptimMUMSAParametersPanel       matlab.ui.container.Panel
        NPLabel                         matlab.ui.control.Label
        OptimMUMSANPEditField           matlab.ui.control.NumericEditField
        itermaxLabel                    matlab.ui.control.Label
        OptimMUMSAIterEditField         matlab.ui.control.NumericEditField
        CPLabel                         matlab.ui.control.Label
        OptimMUMSACPEditField           matlab.ui.control.NumericEditField
        MPLabel                         matlab.ui.control.Label
        OptimMUMSAMPEditField           matlab.ui.control.NumericEditField
        rangeLabel                      matlab.ui.control.Label
        OptimMUMSARangeEditField        matlab.ui.control.NumericEditField
        FLabel                          matlab.ui.control.Label
        OptimMUMSAFEditField            matlab.ui.control.NumericEditField
        RestartsLabel_2                 matlab.ui.control.Label
        OptimMUMSAFullRestartsEditField  matlab.ui.control.NumericEditField
        OptimDETab                      matlab.ui.container.Tab
        OptimUseDECheckBox              matlab.ui.control.CheckBox
        OptimDELabel                    matlab.ui.control.Label
        OptimDEParametersPanel          matlab.ui.container.Panel
        NPLabel_2                       matlab.ui.control.Label
        OptimDENPEditField              matlab.ui.control.NumericEditField
        gen_maxLabel                    matlab.ui.control.Label
        OptimDEGenMaxEditField          matlab.ui.control.NumericEditField
        FLabel_2                        matlab.ui.control.Label
        OptimDEFEditField               matlab.ui.control.NumericEditField
        CRLabel                         matlab.ui.control.Label
        OptimDECREditField              matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        OptimDEXDropDown                matlab.ui.control.DropDown
        yLabel                          matlab.ui.control.Label
        OptimDEYDropDown                matlab.ui.control.DropDown
        RestartsLabel_3                 matlab.ui.control.Label
        OptimDEFullRestartsEditField    matlab.ui.control.NumericEditField
        ApproachLabel                   matlab.ui.control.Label
        OptimApproachDropDown           matlab.ui.control.DropDown
        OptimSummaryLabel               matlab.ui.control.Label
        OptimizeButton                  matlab.ui.control.Button
        RNGLabel                        matlab.ui.control.Label
        OptimRNGSeedEdit                matlab.ui.control.NumericEditField
        OptimResetButton                matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        ResultsPanel                    matlab.ui.container.Panel
        ResultsTabGroup                 matlab.ui.container.TabGroup
        ResultsSynthesisTab             matlab.ui.container.Tab
        ResultsCurveAxes                matlab.ui.control.UIAxes
        ResultsCoordsTable              matlab.ui.control.Table
        ResultsCoordsLabel              matlab.ui.control.Label
        ResultsClearCoordsButton        matlab.ui.control.Button
        ResultsKinematicsTab            matlab.ui.container.Tab
        ResultsKinSimAxes               matlab.ui.control.UIAxes
        ResultsKinStartButton           matlab.ui.control.Button
        ResultsKinAngVelAxes            matlab.ui.control.UIAxes
        ResultsKinAngAccAxes            matlab.ui.control.UIAxes
        th2IniEditFieldLabel            matlab.ui.control.Label
        Resultsth2IniEditField          matlab.ui.control.NumericEditField
        th2EndEditFieldLabel            matlab.ui.control.Label
        Resultsth2EndEditField          matlab.ui.control.NumericEditField
        stepEditFieldLabel              matlab.ui.control.Label
        ResultsstepEditField            matlab.ui.control.NumericEditField
        dTh2EditFieldLabel              matlab.ui.control.Label
        ResultsdTh2EditField            matlab.ui.control.NumericEditField
        ResultsCalcVelAccCheckBox       matlab.ui.control.CheckBox
        ResultsAnglesinradsLabel        matlab.ui.control.Label
        ResultsLinkageparametersLabel   matlab.ui.control.Label
        ResultsLinkageTable             matlab.ui.control.Table
        LogTextAreaLabel                matlab.ui.control.Label
        ResultsLogTextArea              matlab.ui.control.TextArea
        ResultsResetButton              matlab.ui.control.Button
        ResultsPlotButton               matlab.ui.control.Button
        AbsoluteerrorEditFieldLabel_2   matlab.ui.control.Label
        ResultsAbsolutRerrorEditField   matlab.ui.control.NumericEditField
        TDerrorLabel                    matlab.ui.control.Label
        ResultsTDerrorEditField         matlab.ui.control.NumericEditField
        NSDVerrorLabel                  matlab.ui.control.Label
        ResultsNSDVerrorEditField       matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ProblemDefinitioneTableHeaders = {'Cx', 'Cy', [char(hex2dec('03B8')),'2 (rad)'], ['Lb ', char(hex2dec('03B8')),'2 (rad)'], ['Ub ', char(hex2dec('03B8')),'2 (rad)']};
        ProblemDefinitionStructName = 'mechsyncontext';  % Name required to structws with problem definitions
        ProblemDefinitionTypeName = 'prescribed';
        ProblemDefinitionTypeDropDownVals = uint8([3, 1, 2]);
        ProblemDefinitionIsFixedName = 'fixed';
        ProblemDefinitionIsClosedName = 'closed';
        ProblemDefinitionTableName = 'pointTable';
        
        OptimizationApproachData = uint8([0, 1]);
        OptimizationStructName = 'mechsynoptimizers';
        OptimizationApproachName = 'cost_method';
        OptimizationSeedName = 'seed';
        
        OptNames; % Struct with the general field names of every optimizer (to be loaded at startup)
        % FMC
        OptimizationFMCSetOfNames = {};
        OptimizationFMCSetOfIDs = {};
        % TLBO
        OptimizationTLBOSetOfNames = {};
        OptimizationTLBOSetOfIDs = {};
        % MUMSA
        OptimizationMUMSASetOfNames = {};
        OptimizationMUMSASetOfIDs = {};
        % DE
        OptimizationDEXDropDownVals = uint8([0, 1]);
        OptimizationDEYDropDownVals = uint8([1, 2]);
        OptimizationDESetOfNames = {};
        OptimizationDESetOfIDs = {};
        
        % Results        
        ResultsLinkageNumVars = 10;
        ResultsStructName = 'mechsynresults';
        ResultsAcievedPointsTableName = 'achievedCoords';
        ResultsLinkageTableName = 'linkageVars';
        ResultsAEValueName = 'absoluteerror';
        ResultsNSDVValueName = 'nsdverror';
        ResultsTDValueName = 'tderror';
        ResultsLogName = 'log';
    end
    
    methods (Access = private)
        
        function available = hasToolbox(~, name)
            v = ver; % See: https://www.mathworks.com/matlabcentral/answers/37252-how-to-test-if-toolbox-exists
            available = any(strcmp(cellstr(char(v.Name)), name));
        end
        
        function parseCheckBox(~, loadedStruct, fieldName, control)
            control.Value = false; % False by default
            if isfield(loadedStruct, fieldName) && islogical(loadedStruct.(fieldName)) && length(loadedStruct.(fieldName)) == 1 % https://es.mathworks.com/help/matlab/ref/logicaloperatorsshortcircuit.html
                if loadedStruct.(fieldName)
                    control.Value = true;
                end
            else
                msgbox(['The struct provided does not have a boolean ', fieldName, ' field. Assuming it is false.'], 'Missing field','warn');
            end
        end
        
        function parseDropDown(~, loadedStruct, fieldName, control)
            control.Value = control.ItemsData(1); % Selecting the first one by default
            if isfield(loadedStruct, fieldName) && isinteger(loadedStruct.(fieldName)) && length(loadedStruct.(fieldName)) == 1
                readNumber = loadedStruct.(fieldName);
                if ismember(readNumber, control.ItemsData)
                    control.Value = readNumber;
                else
                    msgbox(['The value read for the field (', fieldName, ') is out of the expected range. Selecting the first item by default.'], 'Out-of-range field','warn');
                end
            else
                msgbox(['The struct provided does not have an integer (', fieldName, ') field. Selecting the first item by default.'], 'Missing field','warn');
            end
        end
        
        function parseNumberEditField(~, loadedStruct, fieldName, control, keepInteger)
            control.Value = control.Limits(1); % The first element by default
            if isfield(loadedStruct, fieldName) && ( (keepInteger && isinteger(loadedStruct.(fieldName))) || (~keepInteger && isfloat(loadedStruct.(fieldName))) )
                readNumber = loadedStruct.(fieldName);
                if readNumber >= control.Limits(1) && readNumber <= control.Limits(2)
                    control.Value = double(readNumber);
                else
                     msgbox(['The value read for the field (', fieldName, ') is out of the expected range. Selecting the first value of the range instead.'], 'Out-of-range field','warn');
                end
            else
                msgbox(['The struct provided does not have a number (', fieldName, ') field. Selecting the first value of the range instead.'], 'Missing field','warn');
            end
        end
        
        function parseDoubleTable(~, loadedStruct, fieldName, minRows, maxRows, numCols, control)
            if isfield(loadedStruct, fieldName) && isnumeric(loadedStruct.(fieldName)) && size(loadedStruct.(fieldName), 1)>=minRows && size(loadedStruct.(fieldName), 1)<=maxRows && size(loadedStruct.(fieldName), 2) == numCols
                control.Data = loadedStruct.(fieldName);
            else
                msgbox(['The struct provided does not have a real matrix (', fieldName, ') field of size ', num2str(minRows), 'x', num2str(numCols)], 'Missing field', 'warn');
            end
        end
        
        function parseControlsBlock(app, struct, fieldNames, controls)
            if length(fieldNames) == length(controls)
                for i=1:1:length(fieldNames)
                     switch class(controls{i})
                         case 'matlab.ui.control.NumericEditField'
                            app.parseNumberEditField(struct, fieldNames{i}, controls{i}, controls{i}.RoundFractionalValues);
                         case 'matlab.ui.control.CheckBox'
                            app.parseCheckBox(struct, fieldNames{i}, controls{i});
                         case 'matlab.ui.control.DropDown'
                            app.parseDropDown(struct, fieldNames{i}, controls{i});
                         otherwise
                             error('Unknown control type for parseControlsBlockFuntion');
                     end
                end
            else
                error('Inconsistent lists passed to parseControlsBlockFuntion');
            end
        end
        
        function loadProblemDefinitionStruct(app, loadedStruct)
            if isfield(loadedStruct, app.ProblemDefinitionStructName)
                loadedStruct = loadedStruct.(app.ProblemDefinitionStructName); % https://es.mathworks.com/help/matlab/matlab_prog/generate-field-names-from-variables.html
                    
                app.parseCheckBox(loadedStruct, app.ProblemDefinitionIsFixedName, app.SetupFixtoOriginCheckBox);
                if app.SetupFixtoOriginCheckBox.Value
                    app.OptimApproachDropDown.Value = 0;
                    app.OptimApproachDropDown.Enable = false;
                else
                    app.OptimApproachDropDown.Enable = true;
                end
                app.parseCheckBox(loadedStruct, app.ProblemDefinitionIsClosedName, app.SetupClosedPathCheckBox);
                app.parseDropDown(loadedStruct, app.ProblemDefinitionTypeName, app.SetupTypeDropDown);
                    
                app.parseDoubleTable(loadedStruct, app.ProblemDefinitionTableName, app.SetupNpointsEditField.Limits(1), app.SetupNpointsEditField.Limits(2), length(app.SetupDesiredTable.ColumnName), app.SetupDesiredTable);
                app.SetupNpointsEditField.Value = size(app.SetupDesiredTable.Data, 1);
            else
                msgbox(['The input file must be a workspace image containing a struct ', app.ProblemDefinitionStructName], 'Invalid file','error');
            end
        end
        
        function loadOptimizationDefinitionStruct(app, loadedStruct)
            if isfield(loadedStruct, app.OptimizationStructName)
                loadedStruct = loadedStruct.(app.OptimizationStructName);
                % Overall
                app.parseDropDown(loadedStruct, app.OptimizationApproachName, app.OptimApproachDropDown);
                app.parseNumberEditField(loadedStruct, app.OptimizationSeedName, app.OptimRNGSeedEdit, true);
                % FMC:
                if isfield(loadedStruct, app.OptNames.OptimizationFMCName)
                    if app.hasToolbox('Optimization Toolbox')
                        app.parseControlsBlock(loadedStruct.(app.OptNames.OptimizationFMCName), app.OptimizationFMCSetOfNames, app.OptimizationFMCSetOfIDs);
                    else
                        msgbox('Optimization Toolbox not found. Ignoring the configuration of FMinCon', 'Unavaliable toolbox','warn');
                    end
                else
                    msgbox(['The struct provided does not contain a field (', app.OptimizationFMCName, ')'], 'Missing field','error');
                end
                % TLBO:
                if isfield(loadedStruct, app.OptNames.OptimizationTLBOName)
                    app.parseControlsBlock(loadedStruct.(app.OptNames.OptimizationTLBOName), app.OptimizationTLBOSetOfNames, app.OptimizationTLBOSetOfIDs);
                else
                    msgbox(['The struct provided does not contain a field (', app.OptimizationTLBOName, ')'], 'Missing field','error');
                end
                % MUMSA:
                if isfield(loadedStruct, app.OptNames.OptimizationMUMSAName)
                    app.parseControlsBlock(loadedStruct.(app.OptNames.OptimizationMUMSAName), app.OptimizationMUMSASetOfNames, app.OptimizationMUMSASetOfIDs);
                else
                    msgbox(['The struct provided does not contain a field (', app.OptimizationMUMSAName, ')'], 'Missing field','error');
                end
                % DE:
                if isfield(loadedStruct, app.OptNames.OptimizationDEName)
                    app.parseControlsBlock(loadedStruct.(app.OptNames.OptimizationDEName), app.OptimizationDESetOfNames, app.OptimizationDESetOfIDs);
                else
                    msgbox(['The struct provided does not contain a field (', app.OptimizationDEName, ')'], 'Missing field','error');
                end
                app.updateOptimizationLabel();
            else
                msgbox(['The input file must be a workspace image containing a struct ', app.OptimizationStructName], 'Invalid file','error');
            end
        end
        
        function loadResultDefinitionStruct(app, loadedStruct)
            if isfield(loadedStruct, app.ResultsStructName)
                loadedStruct = loadedStruct.(app.ResultsStructName);
                % Tables for the resulting coordinates and linkage parameters:
                app.parseDoubleTable(loadedStruct, app.ResultsAcievedPointsTableName, app.SetupNpointsEditField.Limits(1), app.SetupNpointsEditField.Limits(2), length(app.ResultsCoordsTable.ColumnName), app.ResultsCoordsTable);
                app.parseDoubleTable(loadedStruct, app.ResultsLinkageTableName, app.ResultsLinkageNumVars, app.ResultsLinkageNumVars, length(app.ResultsLinkageTable.ColumnName), app.ResultsLinkageTable);
                
                % Error metrics:
                app.parseNumberEditField(loadedStruct, app.ResultsAEValueName, app.ResultsAbsolutRerrorEditField, false);
                app.parseNumberEditField(loadedStruct, app.ResultsNSDVValueName, app.ResultsNSDVerrorEditField, false);
                app.parseNumberEditField(loadedStruct, app.ResultsTDValueName, app.ResultsTDerrorEditField, false);
                
                if isfield(loadedStruct, app.ResultsLogName)
                    app.ResultsLogTextArea.Value = loadedStruct.(app.ResultsLogName);
                else
                    msgbox(['The struct provided does not contain a field (', app.ResultsLogName, ')'], 'Missing field','error');
                end
            else
                msgbox(['The input file must be a workspace image containing a struct ', app.ResultsStructName], 'Invalid file','error');
            end
        end
        
        function struct = saveControlsBlock(~, struct, globalName, fieldNames, controls)
            if length(fieldNames) == length(controls)
                for i=1:1:length(fieldNames)
                    if isequal(class(controls{i}), 'matlab.ui.control.NumericEditField') && controls{i}.RoundFractionalValues
                        struct.(globalName).(fieldNames{i}) = uint32(controls{i}.Value);
                    else
                        struct.(globalName).(fieldNames{i}) = controls{i}.Value;
                    end
                end  
            else
                error('Inconsistent lists passed to saveControlsBlockFuntion');
            end
        end
        
        function problemStruct = saveProblemDefinitionStruct(app)
            problemStruct.(app.ProblemDefinitionTypeName) = app.SetupTypeDropDown.Value;
            problemStruct.(app.ProblemDefinitionIsFixedName) = app.SetupFixtoOriginCheckBox.Value;
            problemStruct.(app.ProblemDefinitionIsClosedName) = app.SetupClosedPathCheckBox.Value;
            problemStruct.(app.ProblemDefinitionTableName) = app.SetupDesiredTable.Data;
        end
        
        function optimStruct = saveOptimizationDefinitionStruct(app)
            % Overall
            optimStruct.(app.OptimizationApproachName) = app.OptimApproachDropDown.Value;
            optimStruct.(app.OptimizationSeedName) = uint32(app.OptimRNGSeedEdit.Value);
            % FMC:
            optimStruct = app.saveControlsBlock(optimStruct, app.OptNames.OptimizationFMCName, app.OptimizationFMCSetOfNames, app.OptimizationFMCSetOfIDs);
            % TLBO:
            optimStruct = app.saveControlsBlock(optimStruct, app.OptNames.OptimizationTLBOName, app.OptimizationTLBOSetOfNames, app.OptimizationTLBOSetOfIDs);
            % MUMSA:
            optimStruct = app.saveControlsBlock(optimStruct, app.OptNames.OptimizationMUMSAName, app.OptimizationMUMSASetOfNames, app.OptimizationMUMSASetOfIDs);
            % DE:
            optimStruct = app.saveControlsBlock(optimStruct, app.OptNames.OptimizationDEName, app.OptimizationDESetOfNames, app.OptimizationDESetOfIDs);
        end
        
        function resultStruct = saveResultsDefinitionStruct(app)
            resultStruct.(app.ResultsAcievedPointsTableName) = app.ResultsCoordsTable.Data;
            resultStruct.(app.ResultsLinkageTableName) = app.ResultsLinkageTable.Data;
            resultStruct.(app.ResultsAEValueName) = app.ResultsAbsolutRerrorEditField.Value;
            resultStruct.(app.ResultsNSDVValueName) = app.ResultsNSDVerrorEditField.Value;
            resultStruct.(app.ResultsTDValueName) = app.ResultsTDerrorEditField.Value;
            resultStruct.(app.ResultsLogName) = app.ResultsLogTextArea.Value;
        end
        
        function resetControlsBlock(~, controls)
            for i=1:1:length(controls)
                switch class(controls{i})
                    case 'matlab.ui.control.NumericEditField'
                        controls{i}.Value = controls{i}.Limits(1);
                    case 'matlab.ui.control.DropDown'
                        controls{i}.Value = controls{i}.ItemsData(1);
                    case 'matlab.ui.control.CheckBox'
                        controls{i}.Value = false;
                    otherwise
                        error('Unknown control type for resetControlsBlockFuntion');
                end
            end
        end
        
        function registerOptimFields(app)
            % FMC
            app.OptimizationFMCSetOfNames = {app.OptNames.OptimizationFMCUseName, app.OptNames.OptimizationFMCUStartName, app.OptNames.OptimizationFMCRestartsName};
            app.OptimizationFMCSetOfIDs = {app.OptimUseFMinConCheckBox, app.OptimFMCStartfromCheckBox, app.OptimFMCNRestartsEditField};
            % TLBO
            app.OptimizationTLBOSetOfNames = {app.OptNames.OptimizationTLBOUseName, app.OptNames.OptimizationTLBOPopSizeName, app.OptNames.OptimizationTLBONCyclesName, app.OptNames.OptimizationTLBORestartsName};
            app.OptimizationTLBOSetOfIDs = {app.OptimUseTLBOCheckBox, app.OptimTLBOPopSEditField, app.OptimTLBONCycEditField, app.OptimTLBOFullRestartsEditField};
            % MUMSA
            app.OptimizationMUMSASetOfNames = {app.OptNames.OptimizationMUMSAUseName, app.OptNames.OptimizationMUMSANPName, app.OptNames.OptimizationMUMSACPName, ...
                app.OptNames.OptimizationMUMSAMPName, app.OptNames.OptimizationMUMSARangeName, app.OptNames.OptimizationMUMSAFName, app.OptNames.OptimizationMUMSAItermaxName, app.OptNames.OptimizationMUMSARestartsName};
            app.OptimizationMUMSASetOfIDs = {app.OptimUseMUMSACheckBox, app.OptimMUMSANPEditField, app.OptimMUMSACPEditField, app.OptimMUMSAMPEditField, ...
                app.OptimMUMSARangeEditField, app.OptimMUMSAFEditField, app.OptimMUMSAIterEditField, app.OptimMUMSAFullRestartsEditField};
            % DE
            app.OptimizationDESetOfNames = {app.OptNames.OptimizationDEUseName, app.OptNames.OptimizationDENPName, app.OptNames.OptimizationDECRName, app.OptNames.OptimizationDEFName, ...
                app.OptNames.OptimizationDEXName, app.OptNames.OptimizationDEYName, app.OptNames.OptimizationDEGenMaxName, app.OptNames.OptimizationDERestartsName};
            app.OptimizationDESetOfIDs = {app.OptimUseDECheckBox, app.OptimDENPEditField, app.OptimDECREditField, app.OptimDEFEditField, ...
                app.OptimDEXDropDown, app.OptimDEYDropDown, app.OptimDEGenMaxEditField, app.OptimDEFullRestartsEditField};
        end
        
        function updateOptimizationLabel(app)
            newOptimLabel = 'Enabled: ';
            if app.OptimUseFMinConCheckBox.Value
                newOptimLabel = [newOptimLabel, ' FMinCon, '];
            end
            if app.OptimUseTLBOCheckBox.Value
                newOptimLabel = [newOptimLabel, 'TLBO, '];
            end
            if app.OptimUseMUMSACheckBox.Value
                newOptimLabel = [newOptimLabel, 'MUMSA, '];
            end
            if app.OptimUseDECheckBox.Value
                newOptimLabel = [newOptimLabel, 'DE, '];
            end
            if endsWith(newOptimLabel, ', ')
                newOptimLabel = newOptimLabel(1:length(newOptimLabel)-2);
            end
            app.OptimSummaryLabel.Text = newOptimLabel;
        end
        
        function [correct, XY_des] = packDesiredPath(app)
            correct = true;
            XY_des = app.SetupDesiredTable.Data(:,1:2);
            if any(isnan(XY_des)) % Potential checks: Repeated points...
                correct = false;
                msgbox('Problem definition: Coordinates of the target path (first two columns) cannot contain NaN values', 'Invalid values','error');
            end
        end
        
        function [correct, x0] = packCurrentResult(app)
            correct = true;
            x0 = [];
            dataSet = app.ResultsLinkageTable.Data;
            
            if any(isnan(dataSet(1:6,3))) % Common (last column only, the previous ones should be already valid)
                correct = false;
                msgbox('Linkage parameters: The current result from R1 to RCY cannot be NaN', 'Invalid range','error');
            else
                x0 = dataSet(1:6, 3); % The first 6 parameters are always the same
                if any(x0<dataSet(1:6, 1))
                    correct = false;
                    msgbox(['Linkage parameters: The current result violates the lower bound at row(s) ', mat2str(find(x0<dataSet(1:6, 1)))], 'Invalid range','error');
                elseif any(x0>dataSet(1:6, 2))
                    correct = false;
                    msgbox(['Linkage parameters: The current result violates the upper bound at row(s) ', mat2str(find(x0>dataSet(1:6, 2)))], 'Invalid range','error');
                end
                if correct && app.SetupTypeDropDown.Value > 1 % No more angles for type 1. Yes for 2 and 3
                    if app.SetupTypeDropDown.Value == 2
                        if isnan(dataSet(10, 3)) || dataSet(10, 3) < dataSet(10, 1) || dataSet(10, 3) > dataSet(10, 2) 
                            correct = false;
                            msgbox('Linkage parameters: The current result of the last row must not be NaN, and has to be between its Lb and Ub', 'Invalid range','error');
                        else
                            x0 = [x0; dataSet(10, 3)];
                        end
                    elseif app.SetupTypeDropDown.Value == 3
                        angleSet = app.SetupDesiredTable.Data(:, 3:5); % Th2 plus the last two columns with the theta range
                        if any(isnan(angleSet(:,1)))
                            correct = false;
                            msgbox('Problem definition: The current result cannot have NaN values for the theta angle (third column of the Problem Definition table)', 'Invalid values', 'error');
                        else
                            x0 = [x0; angleSet(:,1)];
                            if any(angleSet(:,1)<angleSet(:,2))
                                correct = false;
                                msgbox(['Problem definition: The current result cannot be lower than its lower boun at row(s) ', mat2str(find(angleSet(:,1)<angleSet(:,2)))], 'Invalid range', 'error');
                            elseif any(angleSet(:,1)>angleSet(:,3))
                                correct = false;
                                msgbox(['Problem definition: The current result cannot be lower than its lower boun at row(s) ', mat2str(find(angleSet(:,1)>angleSet(:,3)))], 'Invalid range', 'error');
                            end
                        end
                    end
                end
                if correct && ~app.SetupFixtoOriginCheckBox.Value % The last 3 parameters are always the same when not fixed to origin
                    if any(isnan(dataSet(7:9,3))) % Common
                        correct = false;
                        msgbox('Linkage parameters: The current result of X0, Y0 and Th0 cannot be NaN', 'Invalid range','error');
                    else
                        x0 = [x0; dataSet(7:9, 3)];
                        if any(dataSet(7:9, 3) < dataSet(7:9, 1))
                            correct = false;
                            msgbox(['Linkage parameters: The current result cannot be lower thant its lower bound at row(s) ', mat2str(find(dataSet(7:9, 3) < dataSet(7:9, 1)))], 'Invalid range','error');
                        elseif any(dataSet(7:9, 3) > dataSet(7:9, 2))
                            correct = false;
                            msgbox(['Linkage parameters: The current result cannot be greater thant its upper bound at row(s) ', mat2str(find(dataSet(7:9, 3) > dataSet(7:9, 2)))], 'Invalid range','error');
                        end
                    end
                end
            end
        end
        
        function [correct, Lb, Ub] = packOptimProblemBounds(app)
            correct = true;
            Lb = [];
            Ub = [];
            dataSet = app.ResultsLinkageTable.Data(:, 1:2);
            
            if any(isnan(dataSet(1:6,1))) || any(isnan(dataSet(1:6,2))) % Common
                correct = false;
                msgbox('Linkage parameters: The bounds from R1 to RCY cannot be NaN', 'Invalid range','error');
            else
                Lb = dataSet(1:6, 1); % The first 6 parameters are always the same
                Ub = dataSet(1:6, 2);
                if any(Lb>Ub)
                    correct = false;
                    msgbox(['Linkage parameters: The lower bound of row(s) ', mat2str(find(Lb>Ub)), ' cannot be greater than its upper one.'], 'Invalid range','error');
                end
                if correct && app.SetupTypeDropDown.Value > 1 % No more angles for type 1. Yes for 2 and 3
                    if app.SetupTypeDropDown.Value == 2
                        if isnan(dataSet(10, 1)) || isnan(dataSet(10, 2)) || dataSet(10, 1)>dataSet(10, 2)
                            correct = false;
                            msgbox('Linkage parameters: min and max values of the last row must not be NaN, and that in Lb has to be lower than that in Ub', 'Invalid range','error');
                        else
                            Lb = [Lb; dataSet(10, 1)];
                            Ub = [Ub; dataSet(10, 2)];
                        end
                    elseif app.SetupTypeDropDown.Value == 3
                        angleSet = app.SetupDesiredTable.Data(:, 4:5); % The last two columns have the theta range
                        if any(isnan(angleSet))
                            correct = false;
                            msgbox('Problem definition: The problem type requires specifying valid lower and upper bounds for the theta angle (last two columns of the Problem Definition table)', 'Invalid values', 'error');
                        else
                            Lb = [Lb; angleSet(:,1)];
                            Ub = [Ub; angleSet(:,2)];
                            if any(angleSet(:,1)>angleSet(:,2))
                                correct = false;
                                 msgbox(['Problem definition: The lower bound of row(s) ', mat2str( find( angleSet(:,1) > angleSet(:,2) ) ), ' cannot be greater than its upper one.'], 'Invalid range', 'error');
                            end
                        end
                    end
                end
                if correct && ~app.SetupFixtoOriginCheckBox.Value % The last 3 parameters are always the same when not fixed to origin
                    if any(isnan(dataSet(7:9,1))) || any(isnan(dataSet(7:9,2))) % Common
                        correct = false;
                        msgbox('Linkage parameters: The bounds of X0, Y0 and Th0 cannot be NaN', 'Invalid range','error');
                    else
                        Lb = [Lb; dataSet(7:9, 1)];
                        Ub = [Ub; dataSet(7:9, 2)];
                        if any(dataSet(7:9, 1)>dataSet(7:9, 2))
                            correct = false;
                            msgbox(['Linkage parameters: The lower bound of row(s) ', mat2str( find( dataSet(7:9, 1) > dataSet(7:9, 2) ) ), ' cannot be greater than its upper one.'], 'Invalid range','error');
                        end
                    end
                end
            end
        end
        
        function optimConfig = readCurrentOptimConfig(app)
            optimConfig = [];
            if app.OptimUseFMinConCheckBox.Value
                optimConfig = app.saveControlsBlock(optimConfig, app.OptNames.OptimizationFMCName, app.OptimizationFMCSetOfNames, app.OptimizationFMCSetOfIDs);
                if optimConfig.(app.OptNames.OptimizationFMCName).(app.OptNames.OptimizationFMCUStartName)
                    [valid, x0] = app.packCurrentResult();
                    if valid
                        optimConfig.(app.OptNames.OptimizationFMCName).(app.OptNames.OptimizationFMCX0Name) = x0; 
                    else
                        optimConfig.(app.OptNames.OptimizationFMCName).(app.OptNames.OptimizationFMCUStartName) = false;
                        msgbox('Warning: The current result is not valid. It will be ignored.', 'Invalid value','warn');
                    end
                end
            end
            if app.OptimUseTLBOCheckBox.Value
                optimConfig = app.saveControlsBlock(optimConfig, app.OptNames.OptimizationTLBOName, app.OptimizationTLBOSetOfNames, app.OptimizationTLBOSetOfIDs);
            end
            if app.OptimUseMUMSACheckBox.Value
                optimConfig = app.saveControlsBlock(optimConfig, app.OptNames.OptimizationMUMSAName, app.OptimizationMUMSASetOfNames, app.OptimizationMUMSASetOfIDs);
            end
            if app.OptimUseDECheckBox.Value
                optimConfig = app.saveControlsBlock(optimConfig, app.OptNames.OptimizationDEName, app.OptimizationDESetOfNames, app.OptimizationDESetOfIDs);
            end
        end
        
        function ShowResult(app, optimContext, optimResult)
            app.ResultsLogTextArea.Value = optimResult.(app.OptNames.LogName); % Always display the log!
            if optimResult.(app.OptNames.bestXRawValName) < optimContext.errorThreshold
                app.ResultsCoordsTable.Data = optimResult.(app.OptNames.XYachievedName);
                
                app.ResultsAbsolutRerrorEditField.Value = optimResult.(app.OptNames.costAEName);
                app.ResultsNSDVerrorEditField.Value = optimResult.(app.OptNames.costNSDVName);
                app.ResultsTDerrorEditField.Value = optimResult.(app.OptNames.costTDName);
                
                if ~isequal(app.SetupDesiredTable.Data(:, 3),  optimResult.(app.OptNames.UpdatedTh2Name))
                    app.SetupDesiredTable.Data(:, 3) = optimResult.(app.OptNames.UpdatedTh2Name);
                    msgbox('The optimization procedure had to reorder the set of Theta_2 angles, the values will be modified accordingly on the table', 'Theta_2 changed', 'warn');
                end
                
                % Let us adapt the internal result to be displayed:
                X1 = optimResult.(app.OptNames.bestXName); % Raw set of linkage parameters
                app.ResultsLinkageTable.Data(1:6, 3) = X1(1:6); % The first six items are always the same
                
                if ~app.SetupFixtoOriginCheckBox.Value
                    endX1 = length(X1);
                    app.ResultsLinkageTable.Data(7:9, 3) = X1([endX1-2, endX1-1, endX1]);
                else
                    app.ResultsLinkageTable.Data(7:9, 3) = 0;
                end
                
                if app.SetupTypeDropDown.Value == 2 % th20 is the last field
                    app.ResultsLinkageTable.Data(length(app.ResultsLinkageTable.Data(:, 3)), 3) = X1(7);
                else
                    app.ResultsLinkageTable.Data(length(app.ResultsLinkageTable.Data(:, 3)), 3) = nan;
                end
                
                msgbox('Process completed!', 'Success', 'help');
            else
                msgbox('The optimization process did not find any valid solution. Tip: Try to enable more methods and provide FMinCon with a promising starting point.', 'No solution', 'error');
                app.resetControlsBlock({app.ResultsAbsolutRerrorEditField, app.ResultsNSDVerrorEditField, app.ResultsTDerrorEditField});
            end
        end
        
        function FinalizeSimulationPlot(app, TPs, th2simul)
            hold(app.ResultsKinSimAxes,"on")
            plot(app.ResultsKinSimAxes, TPs.xC, TPs.yC, 'm', 'linewidth',1)
            plot(app.ResultsKinSimAxes, app.SetupDesiredTable.Data(:, 1), app.SetupDesiredTable.Data(:,2), 'ob', 'MarkerFaceColor','r','MarkerEdgeColor','k', 'MarkerSize',4)
            hold(app.ResultsKinSimAxes,"off");
            if app.ResultsCalcVelAccCheckBox.Value==1
                plot(app.ResultsKinAngVelAxes, th2simul, TPs.dth3,'r', 'linewidth',1); 
                hold(app.ResultsKinAngVelAxes, "on");
                plot(app.ResultsKinAngVelAxes, th2simul, TPs.dth4, 'k', 'linewidth', 1);
                legend(app.ResultsKinAngVelAxes, '\omega_3', '\omega_4');
                hold(app.ResultsKinAngVelAxes, "off");
             
                plot(app.ResultsKinAngAccAxes, th2simul, TPs.ddth3, 'r', 'linewidth',1); 
                hold(app.ResultsKinAngAccAxes, "on");
                plot(app.ResultsKinAngAccAxes,th2simul, TPs.ddth4,'k', 'linewidth',1);
                legend(app.ResultsKinAngAccAxes, '\alpha_3', '\alpha_4');
                hold(app.ResultsKinAngAccAxes, "off");
           end
        end
        
        function EnableWindowActions(app, val)
            % Problem definition zone:
            InvolvedControls = {app.SetupNpointsEditField, app.SetupTypeDropDown, app.SetupFixtoOriginCheckBox, app.SetupClosedPathCheckBox, ...
                app.SetupDesiredTable, app.SetupPreviewButton, app.SetupResetButton};
            % Optimization zone
            InvolvedControls = [InvolvedControls, app.OptimizationFMCSetOfIDs, app.OptimizationTLBOSetOfIDs, app.OptimizationMUMSASetOfIDs, app.OptimizationDESetOfIDs, ...
                {app.OptimApproachDropDown, app.OptimRNGSeedEdit, app.OptimResetButton, app.OptimizeButton}];
            % Results zone
            InvolvedControls = [InvolvedControls, {app.ResultsLinkageTable, app.ResultsLogTextArea, app.ResultsTDerrorEditField, app.ResultsNSDVerrorEditField,...
                app.ResultsAbsolutRerrorEditField, app.ResultsPlotButton, app.ResultsResetButton, app.ResultsCoordsTable, app.ResultsKinStartButton, ...
                app.Resultsth2IniEditField, app.Resultsth2EndEditField, app.ResultsstepEditField, app.ResultsdTh2EditField, app.ResultsCalcVelAccCheckBox, app.ResultsClearCoordsButton}];
            
            InvolvedControls = [InvolvedControls, {app.FileMenu}]; % MENU
            
            for i=1:1:length(InvolvedControls)
                if isequal(class(InvolvedControls{i}), 'matlab.ui.control.Table')
                    if val
                        InvolvedControls{i}.Enable = 'on';
                    else
                        InvolvedControls{i}.Enable = 'off';
                    end
                else
                    InvolvedControls{i}.Enable = val;
                end
                drawnow;
            end
            
            if app.SetupFixtoOriginCheckBox.Value
                app.OptimApproachDropDown.Value = 0;
                app.OptimApproachDropDown.Enable = false; % No matter VAL, is Fixed to origin, only absolute approach.
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SetupTypeDropDown.ItemsData = app.ProblemDefinitionTypeDropDownVals;
            app.SetupDesiredTable.ColumnName = app.ProblemDefinitioneTableHeaders;
            app.SetupDesiredTable.Data =  nan(app.SetupNpointsEditField.Value, length(app.SetupDesiredTable.ColumnName));
            
            rng('shuffle');
            app.OptimRNGSeedEdit.Value = randi(app.OptimRNGSeedEdit.Limits(2));
            app.OptimApproachDropDown.ItemsData = app.OptimizationApproachData;
            if ~app.hasToolbox('Optimization Toolbox')
                app.OptimUseFMinConCheckBox.Text = 'Use FMinCon (Optimization Toolbox not available)';
                app.OptimUseFMinConCheckBox.Value = false;
                app.OptimUseFMinConCheckBox.Enable = false;
                app.OptimFMCStartfromCheckBox.Enable = false;
                app.OptimFMCNRestartsEditField.Enable = false;
            end
            app.OptimDEXDropDown.ItemsData = app.OptimizationDEXDropDownVals;
            app.OptimDEYDropDown.ItemsData = app.OptimizationDEYDropDownVals;
            app.OptNames = LoadOptimNames();
            app.registerOptimFields(); % Creating a grid of controls
            
            app.ResultsLinkageTable.Data = nan(app.ResultsLinkageNumVars, length(app.ResultsLinkageTable.ColumnName));
            app.ResultsCoordsTable.Data = nan(app.SetupNpointsEditField.Value, length(app.ResultsCoordsTable.ColumnName));
            app.th2IniEditFieldLabel.Text = [char(hex2dec('03B8')),'2Ini'];
            app.th2EndEditFieldLabel.Text = [char(hex2dec('03B8')),'2End'];
            app.dTh2EditFieldLabel.Text = [char(hex2dec('03C9')),'2'];
        end

        % Value changed function: SetupNpointsEditField
        function SetupNpointsEditFieldValueChanged(app, event)
            value = app.SetupNpointsEditField.Value;
            preValue = event.PreviousValue;
            if value > preValue
                app.SetupDesiredTable.Data = [app.SetupDesiredTable.Data; nan(value-preValue, length(app.SetupDesiredTable.ColumnName))];
            elseif value < preValue
                app.SetupDesiredTable.Data = app.SetupDesiredTable.Data(1:value,:);
            end
        end

        % Value changed function: SetupFixtoOriginCheckBox
        function SetupFixtoOriginCheckBoxValueChanged(app, event)
            value = app.SetupFixtoOriginCheckBox.Value;
            if value
                app.OptimApproachDropDown.Value = 0;
                app.OptimApproachDropDown.Enable = false;
            else
                app.OptimApproachDropDown.Enable = true;
            end
        end

        % Button pushed function: SetupResetButton
        function SetupResetButtonPushed(app, event)
            app.SetupTypeDropDown.Value = app.ProblemDefinitionTypeDropDownVals(1);
            app.SetupFixtoOriginCheckBox.Value = false;
            app.SetupClosedPathCheckBox.Value = false;
            app.SetupNpointsEditField.Value = 1;
            app.SetupDesiredTable.Data = nan(app.SetupNpointsEditField.Value, length(app.SetupDesiredTable.ColumnName));
        end

        % Button pushed function: SetupPreviewButton
        function SetupPreviewButtonPushed(app, event)
            xCoords = app.SetupDesiredTable.Data(:, 1);
            yCoords = app.SetupDesiredTable.Data(:, 2);
            if (sum(isnan(xCoords)) + sum(isnan(yCoords))) == 0
                if  app.SetupClosedPathCheckBox.Value % To display a closed path, put a last vertex which is equal to the first one
                    xCoords = [xCoords; xCoords(1)];
                    yCoords = [yCoords; yCoords(1)];
                end
                plot(app.ResultsCurveAxes, xCoords, yCoords, '-o');
            else
                msgbox('The problem definition table contains NaN values for columns 1 and 2', 'Invalid coordindates','error');
            end
        end

        % Button pushed function: OptimResetButton
        function OptimResetButtonPushed(app, event)
            app.OptimApproachDropDown.Value = app.OptimApproachDropDown.ItemsData(1);
            app.OptimRNGSeedEdit.Value = randi(app.OptimRNGSeedEdit.Limits(2));
            app.resetControlsBlock(app.OptimizationFMCSetOfIDs); % FMC
            app.resetControlsBlock(app.OptimizationTLBOSetOfIDs); % TLBO
            app.resetControlsBlock(app.OptimizationMUMSASetOfIDs); % MUMSA
            app.resetControlsBlock(app.OptimizationDESetOfIDs); % DE
            app.updateOptimizationLabel();
        end

        % Value changed function: OptimUseFMinConCheckBox
        function OptimUseFMinConCheckBoxValueChanged(app, event)
            app.updateOptimizationLabel();
        end

        % Value changed function: OptimUseTLBOCheckBox
        function OptimUseTLBOCheckBoxValueChanged(app, event)
            app.updateOptimizationLabel();
        end

        % Value changed function: OptimUseMUMSACheckBox
        function OptimUseMUMSACheckBoxValueChanged(app, event)
            app.updateOptimizationLabel();
        end

        % Value changed function: OptimUseDECheckBox
        function OptimUseDECheckBoxValueChanged(app, event)
            app.updateOptimizationLabel();
        end

        % Value changed function: OptimDEFEditField
        function OptimDEFEditFieldValueChanged(app, event)
            value = app.OptimDEFEditField.Value;
            if value < 0
                if value ~= -1 && value ~= -2 % There are only 2 negative options
                    msgbox('The F parameter of DE, when negative, can only be -1 or -2', 'Invalid value','warn');
                    app.OptimDEFEditField.Value = -1; % By default
                end
            end
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            if isnan(app.ResultsLinkageTable.Data(1,1)) % Prelimiary check
                msgbox('It seems that the bounds in the linkage parameter table have not been completed. The first two columns define the search space. Complete them appropriately depending on the problem type.', ...
                    'Invalid value','error');
            else
                [correct, Lb, Ub] = app.packOptimProblemBounds();
                if correct
                    [correct, XY_des] = app.packDesiredPath();
                end
                if correct
                    costType = app.OptimApproachDropDown.Value;
                    probType = app.SetupTypeDropDown.Value;
                    th2_n = app.SetupDesiredTable.Data(:, 3);
                    if any(isnan(th2_n)) && probType~=3 % Type 3 is the only one which does not need input angles
                        msgbox('The problem type requires a valid not-NaN set of angles at the problem definition', 'Invalid value', 'error');
                    else
                        optimConfig = app.readCurrentOptimConfig(); % Read the information of every optimizer
                        if ~isempty(optimConfig)
                            rng(app.OptimRNGSeedEdit.Value); % Using the seed!
                            app.EnableWindowActions(false);
                            [optimContext, optimResult] = Optimize(XY_des, th2_n, [Lb, Ub], costType, probType, optimConfig);
                            finalResult = PostProcessResult(optimContext, optimResult, app.SetupClosedPathCheckBox.Value);
                            app.ShowResult(optimContext, finalResult);
                            app.EnableWindowActions(true);
                        else
                            msgbox('Please, enable any of the optimizers provided to proceed', 'Nothing to do', 'error');
                        end
                    end
                end
            end
        end

        % Button pushed function: ResultsPlotButton
        function ResultsPlotButtonPushed(app, event)
            Cx_des = app.SetupDesiredTable.Data(:, 1);
            Cy_des = app.SetupDesiredTable.Data(:, 2);
            if any(isnan(Cx_des)) || any(isnan(Cy_des))
                msgbox('The set of coordinates of the desired path must be not NaN.', 'Invalid desired path','error');
            else
                Cx_act = app.ResultsCoordsTable.Data(:,1);
                Cy_act = app.ResultsCoordsTable.Data(:,2);
                go = true;
                if any(isnan(Cx_act)) || any(isnan(Cy_act)) || length(Cx_des) ~= length(Cx_act)
                    go = false;
                    selection = uiconfirm(app.SALARMechanismsynthesizerUIFigure, ['The current achieved path does not seem to correspond to', ...
                        ' the current desired path. Do you want to update it with the current result?'],'Simulate current configuration?');
                    if isequal(selection, 'OK')
                        [valid, x] = app.packCurrentResult();
                        if valid
                            miniContext.lenRefXY = length(Cx_des);
                            miniContext.prescribed = app.SetupTypeDropDown.Value;
                            if miniContext.prescribed ~= 3
                                miniContext.th2_n = app.SetupDesiredTable.Data(:, 3);
                            end
                            if ~isfield(miniContext, 'th2_n') || ( isfield(miniContext, 'th2_n') && ~any(isnan(miniContext.th2_n)) )
                                miniTable = get_XY(x, miniContext);
                                if ~any(isnan(miniTable(:)))
                                    go = true;
                                    Cx_act = miniTable(:, 1);
                                    Cy_act = miniTable(:, 2);
                                    app.ResultsCoordsTable.Data = miniTable;
                                    app.ResultsAbsolutRerrorEditField.Value = sum( (Cx_act - Cx_des).^2 + (Cy_act - Cy_des).^2 );
                                else
                                    msgbox('The current configuration lead to an invalid result', 'Invalid result', 'error');
                                end
                            else
                                msgbox('The theta_2_n input of the problem definition table must be not NaN', 'Invalid configuration', 'error');
                            end
                        else
                            msgbox('The current configuration cannot be run', 'Invalid configuration', 'error');
                        end
                    end
                end
                if go
                    if app.SetupClosedPathCheckBox.Value
                        plot(app.ResultsCurveAxes, [Cx_des;Cx_des(1)], [Cy_des;Cy_des(1)], 'o-b', [Cx_act;Cx_act(1)], [Cy_act;Cy_act(1)], '*--r');
                    else
                        plot(app.ResultsCurveAxes, Cx_des, Cy_des, 'o-b', Cx_act, Cy_act, '*--r');
                    end
                    text(app.ResultsCurveAxes, Cx_des(1), Cy_des(1), '1');
                    text(app.ResultsCurveAxes, Cx_des(2), Cy_des(2), '2');
                    text(app.ResultsCurveAxes, Cx_act(1), Cy_act(1), '1');
                    text(app.ResultsCurveAxes, Cx_act(2), Cy_act(2), '2');
                    axis(app.ResultsCurveAxes, 'equal');            
                end
            end
        end

        % Button pushed function: ResultsKinStartButton
        function ResultsKinStartButtonPushed(app, event)
            X = app.ResultsLinkageTable.Data(1:9, 3);
            if any(isnan(X))
                msgbox('The linkage parameters and the theta_2 points must be valid, not NaN values', 'Invalid values', 'error');
            else
                if (app.Resultsth2IniEditField.Value < app.Resultsth2EndEditField.Value && app.ResultsstepEditField.Value > 0) || ...
                        (app.Resultsth2IniEditField.Value > app.Resultsth2EndEditField.Value && app.ResultsstepEditField.Value < 0)
                    app.EnableWindowActions(false);
                    th2simul= app.Resultsth2IniEditField.Value:app.ResultsstepEditField.Value:app.Resultsth2EndEditField.Value;
                    TPs = premoving(app.ResultsdTh2EditField.Value, X, th2simul,app.ResultsCalcVelAccCheckBox.Value);
                    % Normalize the size of the arrows wrt the shortest bar length
                    if app.ResultsCalcVelAccCheckBox.Value==1
                        scale_acc = X(2) / max(abs([TPs.axA;TPs.ayA;TPs.axB;TPs.ayB;TPs.axC;TPs.ayC]));
                        scale_vel = X(2) / max(abs([TPs.vxA;TPs.vyA;TPs.vxB;TPs.vyB;TPs.vxC;TPs.vyC]));
                    end
                    x0=X(7); y0=X(8); th0=X(9);
                    if isnan(x0); x0=0; end
                    if isnan(y0); y0=0; end
                    if isnan(th0); th0=0; end
                    xO4 = x0 + X(1)*cos(th0); 
                    yO4 = y0 + X(1)*sin(th0);
                    minX = min([TPs.xA; TPs.xB; TPs.xC; x0; xO4]);
                    maxX = max([TPs.xA; TPs.xB; TPs.xC; x0; xO4]);
                    minY = min([TPs.yA; TPs.yB; TPs.yC; y0; yO4]);
                    maxY = max([TPs.yA; TPs.yB; TPs.yC; y0; yO4]);
                    distances = [maxX-minX, maxY-minY];
                    
                    for i=1:length(th2simul)
                        cla(app.ResultsKinSimAxes,"reset");
                        % Mechanism:
                        fill(app.ResultsKinSimAxes,[TPs.xA(i),TPs.xC(i),TPs.xB(i)],[TPs.yA(i),TPs.yC(i),TPs.yB(i)],[0.8 0.8 0.8]), hold(app.ResultsKinSimAxes,"on")
                        line(app.ResultsKinSimAxes,[x0,TPs.xA(i),TPs.xB(i),xO4],[y0,TPs.yA(i),TPs.yB(i),yO4],'Color',[0.2 0.2 0.2],'lineWidth',3,'Marker','o','Markersize',2.5);
                        % Vel. and acceleration Arrows:
                        if app.ResultsCalcVelAccCheckBox.Value==1
                            quiver(app.ResultsKinSimAxes,TPs.xA(i),TPs.yA(i),scale_vel*TPs.vxA(i),scale_vel*TPs.vyA(i),"Color",'b',"LineWidth",1);
                            quiver(app.ResultsKinSimAxes,TPs.xB(i),TPs.yB(i),scale_vel*TPs.vxB(i),scale_vel*TPs.vyB(i),"Color",'b',"LineWidth",1)
                            quiver(app.ResultsKinSimAxes,TPs.xC(i),TPs.yC(i),scale_vel*TPs.vxC(i),scale_vel*TPs.vyC(i),"Color",'b',"LineWidth",1)
                            quiver(app.ResultsKinSimAxes,TPs.xA(i),TPs.yA(i),scale_acc*TPs.axA(i),scale_acc*TPs.ayA(i),"Color",'r',"LineWidth",1);
                            quiver(app.ResultsKinSimAxes,TPs.xB(i),TPs.yB(i),scale_acc*TPs.axB(i),scale_acc*TPs.ayB(i),"Color",'r',"LineWidth",1)
                            quiver(app.ResultsKinSimAxes,TPs.xC(i),TPs.yC(i),scale_acc*TPs.axC(i),scale_acc*TPs.ayC(i),"Color",'r',"LineWidth",1), hold(app.ResultsKinSimAxes,"off")
                        end
                        % Limits:
                        xlim(app.ResultsKinSimAxes,[minX-X(2), minX + max(distances) + X(2)])
                        ylim(app.ResultsKinSimAxes,[minY-X(2), minY + max(distances) + X(2)])            
                        if app.ResultsCalcVelAccCheckBox.Value==1
                            title(app.ResultsKinSimAxes,sprintf('Vel (blue) E.1:%.2f; Acc (red) E.1:%.2f',scale_vel,scale_acc))
                        end
                        pause(1e-9);
                    end          
                    app.FinalizeSimulationPlot(TPs, th2simul);                    
                    app.EnableWindowActions(true);
                else
                    msgbox('Invalid conditions: Plase, provide either an initial angle lower than the final one with a positive step or the opposite.', 'Invalid values', 'error');
                end
            end
        end

        % Button pushed function: ResultsResetButton
        function ResultsResetButtonPushed(app, event)
            app.ResultsCoordsTable.Data = nan(app.SetupNpointsEditField.Value, length(app.ResultsCoordsTable.ColumnName));
            app.ResultsLinkageTable.Data = nan(app.ResultsLinkageNumVars, length(app.ResultsLinkageTable.ColumnName));
            app.ResultsAbsolutRerrorEditField.Value = inf;
            app.ResultsNSDVerrorEditField.Value = inf;
            app.ResultsTDerrorEditField.Value = inf;
            app.ResultsLogTextArea.Value = '';
            app.ResultsCurveAxes.cla(); % Clearing the result figure
            % Analysis section:
            app.ResultsKinSimAxes.cla(); % Clearing the kinematics simulation figure
            app.ResultsKinAngVelAxes.cla(); % Clearing the velocity
            app.ResultsKinAngAccAxes.cla(); % Clearing the acceleration
            app.Resultsth2IniEditField.Value = 0;
            app.Resultsth2EndEditField.Value = 0;
            app.ResultsstepEditField.Value = 0;
            app.ResultsdTh2EditField.Value = 0;
        end

        % Button pushed function: ResultsClearCoordsButton
        function ResultsClearCoordsButtonPushed(app, event)
            app.ResultsCoordsTable.Data = nan(1, 2);
            app.ResultsCurveAxes.cla(); % Clearing the result figure
        end

        % Menu selected function: FileSaveMenu
        function FileSaveMenuSelected(app, event)
            app.SALARMechanismsynthesizerUIFigure.Visible = false;
            [filename, pathstr] = uiputfile('*.mat', 'Saving problem definition');
            app.SALARMechanismsynthesizerUIFigure.Visible = true;
            
            if(ischar(filename))
                wspaceFileName = fullfile(pathstr, filename);
                buffer.(app.ProblemDefinitionStructName) = app.saveProblemDefinitionStruct();
                buffer.(app.OptimizationStructName) = app.saveOptimizationDefinitionStruct();
                buffer.(app.ResultsStructName) = app.saveResultsDefinitionStruct();
                try
                    save(wspaceFileName, '-struct', 'buffer');
                    valid = true;
                catch
                    valid = false;
                end
                if valid
                    msgbox('The problem definition has been successfully saved', 'Problem definition saved','help');
                else
                    msgbox('The system failed to create an output file.', 'Saving failed','error');
                end
            end
        end

        % Menu selected function: FileLoadMenu
        function FileLoadMenuSelected(app, event)
            app.SALARMechanismsynthesizerUIFigure.Visible = false;
            [filename, pathstr] = uigetfile('*.mat', 'Select a .mat file with a problem definition struct');
            app.SALARMechanismsynthesizerUIFigure.Visible = true;
            
             if ischar(filename)
                wspaceFileName = fullfile(pathstr, filename);
                try 
                    loadedStruct = load(wspaceFileName);
                    validMatFile = true;
                catch
                    validMatFile = false;
                end
                
                if validMatFile
                    app.loadProblemDefinitionStruct(loadedStruct); % Problem definition section
                    app.loadOptimizationDefinitionStruct(loadedStruct); % Optimization configuration section
                    app.loadResultDefinitionStruct(loadedStruct); % Result section
                else
                    msgbox('The input file must be an appropriate workspace image containing', 'Invalid file','error');
                end
            end % Otherwise, no filed was selected
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SALARMechanismsynthesizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {682, 682};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {465, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SALARMechanismsynthesizerUIFigure = uifigure('Visible', 'off');
            app.SALARMechanismsynthesizerUIFigure.Name = 'SALAR Mechanism synthesizer';
            app.SALARMechanismsynthesizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.SALARMechanismsynthesizerUIFigure);
            app.FileMenu.Text = 'File';
            app.FileLoadMenu = uimenu(app.FileMenu);
            app.FileLoadMenu.MenuSelectedFcn = createCallbackFcn(app, @FileLoadMenuSelected, true);
            app.FileLoadMenu.Text = 'Load file';
            app.FileSaveMenu = uimenu(app.FileMenu);
            app.FileSaveMenu.MenuSelectedFcn = createCallbackFcn(app, @FileSaveMenuSelected, true);
            app.FileSaveMenu.Text = 'Save file';
            app.GridLayout = uigridlayout(app.SALARMechanismsynthesizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ProblemSetupPanel = uipanel(app.LeftPanel);
            app.ProblemSetupPanel.Tooltip = {''};
            app.ProblemSetupPanel.Title = 'Problem setup';
            app.PointsinthereferencepathNLabel = uilabel(app.ProblemSetupPanel);
            app.PointsinthereferencepathNLabel.Text = 'Points in the reference path (N):';
            app.SetupNpointsEditField = uieditfield(app.ProblemSetupPanel, 'numeric');
            app.SetupNpointsEditField.Limits = [1 360];
            app.SetupNpointsEditField.RoundFractionalValues = 'on';
            app.SetupNpointsEditField.ValueDisplayFormat = '%.0f';
            app.SetupNpointsEditField.ValueChangedFcn = createCallbackFcn(app, @SetupNpointsEditFieldValueChanged, true);
            app.SetupNpointsEditField.Tooltip = {'Number of points of the path to replicate (Integer between 1 and 360)'};
            app.SetupNpointsEditField.Value = 1;
            app.TypeDropDownLabel = uilabel(app.ProblemSetupPanel);
            app.TypeDropDownLabel.Text = 'Type:';
            app.SetupTypeDropDown = uidropdown(app.ProblemSetupPanel);
            app.SetupTypeDropDown.Items = {'Non-prescribed timing', 'Prescribed timing', 'Prescribed timing with free starting angle'};
            app.SetupTypeDropDown.Tooltip = {'Problem type'};
            app.SetupTypeDropDown.Value = 'Non-prescribed timing';
            app.SetupFixtoOriginCheckBox = uicheckbox(app.ProblemSetupPanel);
            app.SetupFixtoOriginCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetupFixtoOriginCheckBoxValueChanged, true);
            app.SetupFixtoOriginCheckBox.Tooltip = {'Horizontal ground link to origin in the global reference frame (x0 = y0 = th0 = 0)'};
            app.SetupFixtoOriginCheckBox.Text = 'Fix to origin';
            app.SetupClosedPathCheckBox = uicheckbox(app.ProblemSetupPanel);
            app.SetupClosedPathCheckBox.Tooltip = {'Does the crank produce a complete rotation?'};
            app.SetupClosedPathCheckBox.Text = 'Closed path';
            app.SetupResetButton = uibutton(app.ProblemSetupPanel, 'push');
            app.SetupResetButton.ButtonPushedFcn = createCallbackFcn(app, @SetupResetButtonPushed, true);
            app.SetupResetButton.Tooltip = {'Clear the current problem definition'};
            app.SetupResetButton.Text = 'Reset';
            app.SetupPreviewButton = uibutton(app.ProblemSetupPanel, 'push');
            app.SetupPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SetupPreviewButtonPushed, true);
            app.SetupPreviewButton.Tooltip = {'Plot the desired path'};
            app.SetupPreviewButton.Text = 'Preview';
            app.SetupDesiredTable = uitable(app.ProblemSetupPanel);
            app.SetupDesiredTable.ColumnName = {'Cx'; 'Cy'; 'Th2 (rad)'; 'Lb Th2 (rad)'; 'Ub Th2 (rad)'};
            app.SetupDesiredTable.RowName = {};
            app.SetupDesiredTable.ColumnEditable = [true true true true true];
            app.SetupDesiredTable.Tooltip = {'Points of the desired path, crank angle and its lower and upper bounds'};
            app.OptimizationSetupPanel = uipanel(app.LeftPanel);
            app.OptimizationSetupPanel.Tooltip = {''};
            app.OptimizationSetupPanel.Title = 'Optimization setup';
            app.SalLogoImage = uiimage(app.OptimizationSetupPanel);
            app.SalLogoImage.ImageSource = 'LogoSAL.png';
            app.ARMLogoImage = uiimage(app.OptimizationSetupPanel);
            app.ARMLogoImage.ImageSource = 'LogoARM.jpg';
            app.OptimTabGroup = uitabgroup(app.OptimizationSetupPanel);
            app.OptimFMinConTab = uitab(app.OptimTabGroup);
            app.OptimFMinConTab.Title = 'FMinCon';
            app.OptimUseFMinConCheckBox = uicheckbox(app.OptimFMinConTab);
            app.OptimUseFMinConCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimUseFMinConCheckBoxValueChanged, true);
            app.OptimUseFMinConCheckBox.Text = 'Use FMinCon';
            app.OptimFMinConLabel = uilabel(app.OptimFMinConTab);
            app.OptimFMinConLabel.Text = 'Interior-point implementation shipped with the Optimization Toolbox.';
            app.OptimFMCParametersPanel = uipanel(app.OptimFMinConTab);
            app.OptimFMCParametersPanel.Title = 'Parameters';
            app.OptimFMCStartfromCheckBox = uicheckbox(app.OptimFMCParametersPanel);
            app.OptimFMCStartfromCheckBox.Text = 'Start from the current result';
            app.RandomrestartsLabel = uilabel(app.OptimFMCParametersPanel);
            app.RandomrestartsLabel.Text = 'Random restarts:';
            app.OptimFMCNRestartsEditField = uieditfield(app.OptimFMCParametersPanel, 'numeric');
            app.OptimFMCNRestartsEditField.Limits = [1 999999999];
            app.OptimFMCNRestartsEditField.RoundFractionalValues = 'on';
            app.OptimFMCNRestartsEditField.ValueDisplayFormat = '%.0f';
            app.OptimFMCNRestartsEditField.Tooltip = {''};
            app.OptimFMCNRestartsEditField.Value = 1;
            app.OptimTLBOTab = uitab(app.OptimTabGroup);
            app.OptimTLBOTab.Title = 'TLBO';
            app.OptimUseTLBOCheckBox = uicheckbox(app.OptimTLBOTab);
            app.OptimUseTLBOCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimUseTLBOCheckBoxValueChanged, true);
            app.OptimUseTLBOCheckBox.Text = 'Use TLBO';
            app.OptimTLBOLabel = uilabel(app.OptimTLBOTab);
            app.OptimTLBOLabel.Text = 'Teaching-Learning-Based optimizer proposed by Rao et al., 2012.';
            app.OptimTLBOParametersPanel = uipanel(app.OptimTLBOTab);
            app.OptimTLBOParametersPanel.Title = 'Parameters';
            app.PopulationsizeLabel = uilabel(app.OptimTLBOParametersPanel);
            app.PopulationsizeLabel.Text = 'Population size:';
            app.OptimTLBOPopSEditField = uieditfield(app.OptimTLBOParametersPanel, 'numeric');
            app.OptimTLBOPopSEditField.Limits = [10 999999999];
            app.OptimTLBOPopSEditField.RoundFractionalValues = 'on';
            app.OptimTLBOPopSEditField.ValueDisplayFormat = '%.0f';
            app.OptimTLBOPopSEditField.Tooltip = {''};
            app.OptimTLBOPopSEditField.Value = 10;
            app.NumberofcyclesLabel = uilabel(app.OptimTLBOParametersPanel);
            app.NumberofcyclesLabel.Text = 'Number of cycles:';
            app.OptimTLBONCycEditField = uieditfield(app.OptimTLBOParametersPanel, 'numeric');
            app.OptimTLBONCycEditField.Limits = [1 999999999];
            app.OptimTLBONCycEditField.RoundFractionalValues = 'on';
            app.OptimTLBONCycEditField.ValueDisplayFormat = '%.0f';
            app.OptimTLBONCycEditField.Tooltip = {''};
            app.OptimTLBONCycEditField.Value = 1;
            app.RestartsLabel = uilabel(app.OptimTLBOParametersPanel);
            app.RestartsLabel.Text = 'Restarts:';
            app.OptimTLBOFullRestartsEditField = uieditfield(app.OptimTLBOParametersPanel, 'numeric');
            app.OptimTLBOFullRestartsEditField.Limits = [1 999999999];
            app.OptimTLBOFullRestartsEditField.RoundFractionalValues = 'on';
            app.OptimTLBOFullRestartsEditField.ValueDisplayFormat = '%.0f';
            app.OptimTLBOFullRestartsEditField.Tooltip = {'Number of full repetitions of the method'};
            app.OptimTLBOFullRestartsEditField.Value = 1;
            app.OptimMUMSATab = uitab(app.OptimTabGroup);
            app.OptimMUMSATab.Title = 'MUMSA';
            app.OptimUseMUMSACheckBox = uicheckbox(app.OptimMUMSATab);
            app.OptimUseMUMSACheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimUseMUMSACheckBoxValueChanged, true);
            app.OptimUseMUMSACheckBox.Text = 'Use MUMSA';
            app.OptimMUMSALabel = uilabel(app.OptimMUMSATab);
            app.OptimMUMSALabel.Text = 'Malaga Univ. Mechanism Synth. Alg. proposed by Cabrera et al., 2010.';
            app.OptimMUMSAParametersPanel = uipanel(app.OptimMUMSATab);
            app.OptimMUMSAParametersPanel.Title = 'Parameters';
            app.NPLabel = uilabel(app.OptimMUMSAParametersPanel);
            app.NPLabel.Tooltip = {'Population size'};
            app.NPLabel.Text = 'NP:';
            app.OptimMUMSANPEditField = uieditfield(app.OptimMUMSAParametersPanel, 'numeric');
            app.OptimMUMSANPEditField.Limits = [10 999999999];
            app.OptimMUMSANPEditField.RoundFractionalValues = 'on';
            app.OptimMUMSANPEditField.ValueDisplayFormat = '%.0f';
            app.OptimMUMSANPEditField.Tooltip = {'Population size'};
            app.OptimMUMSANPEditField.Value = 10;
            app.itermaxLabel = uilabel(app.OptimMUMSAParametersPanel);
            app.itermaxLabel.Text = 'itermax:';
            app.OptimMUMSAIterEditField = uieditfield(app.OptimMUMSAParametersPanel, 'numeric');
            app.OptimMUMSAIterEditField.Limits = [1 999999999];
            app.OptimMUMSAIterEditField.RoundFractionalValues = 'on';
            app.OptimMUMSAIterEditField.ValueDisplayFormat = '%.0f';
            app.OptimMUMSAIterEditField.Tooltip = {'Cycles'};
            app.OptimMUMSAIterEditField.Value = 1;
            app.CPLabel = uilabel(app.OptimMUMSAParametersPanel);
            app.CPLabel.Tooltip = {'Population size'};
            app.CPLabel.Text = 'CP:';
            app.OptimMUMSACPEditField = uieditfield(app.OptimMUMSAParametersPanel, 'numeric');
            app.OptimMUMSACPEditField.Limits = [0 1];
            app.OptimMUMSACPEditField.ValueDisplayFormat = '%.2f';
            app.OptimMUMSACPEditField.Tooltip = {'Crossover rate'};
            app.OptimMUMSACPEditField.Value = 0.5;
            app.MPLabel = uilabel(app.OptimMUMSAParametersPanel);
            app.MPLabel.Tooltip = {'Population size'};
            app.MPLabel.Text = 'MP:';
            app.OptimMUMSAMPEditField = uieditfield(app.OptimMUMSAParametersPanel, 'numeric');
            app.OptimMUMSAMPEditField.Limits = [0 1];
            app.OptimMUMSAMPEditField.ValueDisplayFormat = '%.2f';
            app.OptimMUMSAMPEditField.Tooltip = {'Mutation probability'};
            app.OptimMUMSAMPEditField.Value = 0.5;
            app.rangeLabel = uilabel(app.OptimMUMSAParametersPanel);
            app.rangeLabel.Tooltip = {'Mutation range'};
            app.rangeLabel.Text = 'range:';
            app.OptimMUMSARangeEditField = uieditfield(app.OptimMUMSAParametersPanel, 'numeric');
            app.OptimMUMSARangeEditField.Limits = [0 999999999];
            app.OptimMUMSARangeEditField.ValueDisplayFormat = '%.2f';
            app.OptimMUMSARangeEditField.Tooltip = {'Mutation range'};
            app.OptimMUMSARangeEditField.Value = 1;
            app.FLabel = uilabel(app.OptimMUMSAParametersPanel);
            app.FLabel.Tooltip = {'Population size'};
            app.FLabel.Text = 'F:';
            app.OptimMUMSAFEditField = uieditfield(app.OptimMUMSAParametersPanel, 'numeric');
            app.OptimMUMSAFEditField.Limits = [0 2];
            app.OptimMUMSAFEditField.ValueDisplayFormat = '%.2f';
            app.OptimMUMSAFEditField.Tooltip = {'Amplification factor'};
            app.OptimMUMSAFEditField.Value = 0.5;
            app.RestartsLabel_2 = uilabel(app.OptimMUMSAParametersPanel);
            app.RestartsLabel_2.Text = 'Restarts:';
            app.OptimMUMSAFullRestartsEditField = uieditfield(app.OptimMUMSAParametersPanel, 'numeric');
            app.OptimMUMSAFullRestartsEditField.Limits = [1 999999999];
            app.OptimMUMSAFullRestartsEditField.RoundFractionalValues = 'on';
            app.OptimMUMSAFullRestartsEditField.ValueDisplayFormat = '%.0f';
            app.OptimMUMSAFullRestartsEditField.Tooltip = {'Number of full repetitions of the method'};
            app.OptimMUMSAFullRestartsEditField.Value = 1;
            app.OptimDETab = uitab(app.OptimTabGroup);
            app.OptimDETab.Title = 'DE';
            app.OptimUseDECheckBox = uicheckbox(app.OptimDETab);
            app.OptimUseDECheckBox.ValueChangedFcn = createCallbackFcn(app, @OptimUseDECheckBoxValueChanged, true);
            app.OptimUseDECheckBox.Text = 'Use DE';
            app.OptimDELabel = uilabel(app.OptimDETab);
            app.OptimDELabel.Text = 'Differential Evolution proposed by Storn and Price, 1997.';
            app.OptimDEParametersPanel = uipanel(app.OptimDETab);
            app.OptimDEParametersPanel.Title = 'Parameters';
            app.NPLabel_2 = uilabel(app.OptimDEParametersPanel);
            app.NPLabel_2.Tooltip = {'Population size'};
            app.NPLabel_2.Text = 'NP:';
            app.OptimDENPEditField = uieditfield(app.OptimDEParametersPanel, 'numeric');
            app.OptimDENPEditField.Limits = [10 999999999];
            app.OptimDENPEditField.RoundFractionalValues = 'on';
            app.OptimDENPEditField.ValueDisplayFormat = '%.0f';
            app.OptimDENPEditField.Tooltip = {'Population size'};
            app.OptimDENPEditField.Value = 10;
            app.gen_maxLabel = uilabel(app.OptimDEParametersPanel);
            app.gen_maxLabel.Text = 'gen_max:';
            app.OptimDEGenMaxEditField = uieditfield(app.OptimDEParametersPanel, 'numeric');
            app.OptimDEGenMaxEditField.Limits = [1 999999999];
            app.OptimDEGenMaxEditField.RoundFractionalValues = 'on';
            app.OptimDEGenMaxEditField.ValueDisplayFormat = '%.0f';
            app.OptimDEGenMaxEditField.Tooltip = {'Cycles'};
            app.OptimDEGenMaxEditField.Value = 1;
            app.FLabel_2 = uilabel(app.OptimDEParametersPanel);
            app.FLabel_2.Tooltip = {'Population size'};
            app.FLabel_2.Text = 'F:';
            app.OptimDEFEditField = uieditfield(app.OptimDEParametersPanel, 'numeric');
            app.OptimDEFEditField.Limits = [-2 2];
            app.OptimDEFEditField.ValueDisplayFormat = '%.2f';
            app.OptimDEFEditField.ValueChangedFcn = createCallbackFcn(app, @OptimDEFEditFieldValueChanged, true);
            app.OptimDEFEditField.Tooltip = {'Set F in (0, 2] for static amplification factor'; 'Set F = -1 for per-generation dither, i.e., F_G in [0.5, 1.0]'; 'Set F = -2 for per-vector dither, i.e., F_v in [0.5, 1.0]'};
            app.OptimDEFEditField.Value = 0.5;
            app.CRLabel = uilabel(app.OptimDEParametersPanel);
            app.CRLabel.Tooltip = {'Population size'};
            app.CRLabel.Text = 'CR:';
            app.OptimDECREditField = uieditfield(app.OptimDEParametersPanel, 'numeric');
            app.OptimDECREditField.Limits = [0 1];
            app.OptimDECREditField.ValueDisplayFormat = '%.2f';
            app.OptimDECREditField.Tooltip = {'Crossover rate'};
            app.OptimDECREditField.Value = 0.5;
            app.xLabel = uilabel(app.OptimDEParametersPanel);
            app.xLabel.Text = 'x:';
            app.OptimDEXDropDown = uidropdown(app.OptimDEParametersPanel);
            app.OptimDEXDropDown.Items = {'rand', 'best'};
            app.OptimDEXDropDown.Tooltip = {'Vector to be mutated: ''rand'' for a randomly chosen population vector or ''best'' for the best individual at the population'};
            app.OptimDEXDropDown.Value = 'rand';
            app.yLabel = uilabel(app.OptimDEParametersPanel);
            app.yLabel.Text = 'y:';
            app.OptimDEYDropDown = uidropdown(app.OptimDEParametersPanel);
            app.OptimDEYDropDown.Items = {'1', '2'};
            app.OptimDEYDropDown.Tooltip = {'Number of difference vectors used'};
            app.OptimDEYDropDown.Value = '1';
            app.RestartsLabel_3 = uilabel(app.OptimDEParametersPanel);
            app.RestartsLabel_3.Text = 'Restarts:';
            app.OptimDEFullRestartsEditField = uieditfield(app.OptimDEParametersPanel, 'numeric');
            app.OptimDEFullRestartsEditField.Limits = [1 999999999];
            app.OptimDEFullRestartsEditField.RoundFractionalValues = 'on';
            app.OptimDEFullRestartsEditField.ValueDisplayFormat = '%.0f';
            app.OptimDEFullRestartsEditField.Tooltip = {'Number of full repetitions of the method'};
            app.OptimDEFullRestartsEditField.Value = 1;
            app.ApproachLabel = uilabel(app.OptimizationSetupPanel);
            app.ApproachLabel.Text = 'Approach:';
            app.OptimApproachDropDown = uidropdown(app.OptimizationSetupPanel);
            app.OptimApproachDropDown.Items = {'Absolute', 'NSD-Vectors'};
            app.OptimApproachDropDown.Tooltip = {'Select the error estimation method'};
            app.OptimApproachDropDown.Value = 'Absolute';
            app.OptimSummaryLabel = uilabel(app.OptimizationSetupPanel);
            app.OptimSummaryLabel.Text = 'Enabled:';
            app.OptimizeButton = uibutton(app.OptimizationSetupPanel, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.RNGLabel = uilabel(app.OptimizationSetupPanel);
            app.RNGLabel.Text = 'RNG:';
            app.OptimRNGSeedEdit = uieditfield(app.OptimizationSetupPanel, 'numeric');
            app.OptimRNGSeedEdit.Limits = [1 999999999];
            app.OptimRNGSeedEdit.RoundFractionalValues = 'on';
            app.OptimRNGSeedEdit.ValueDisplayFormat = '%.0f';
            app.OptimRNGSeedEdit.Tooltip = {'Seed for the random number generation'};
            app.OptimRNGSeedEdit.Value = 1;
            app.OptimResetButton = uibutton(app.OptimizationSetupPanel, 'push');
            app.OptimResetButton.ButtonPushedFcn = createCallbackFcn(app, @OptimResetButtonPushed, true);
            app.OptimResetButton.Tooltip = {'Clear the current optimization setup'};
            app.OptimResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.ResultsPanel = uipanel(app.RightPanel);
            app.ResultsPanel.Title = 'Results';
            app.ResultsTabGroup = uitabgroup(app.ResultsPanel);
            app.ResultsSynthesisTab = uitab(app.ResultsTabGroup);
            app.ResultsSynthesisTab.Title = 'Synthesis';
            app.ResultsCurveAxes = uiaxes(app.ResultsSynthesisTab);
            title(app.ResultsCurveAxes, 'Coupler curve')
            xlabel(app.ResultsCurveAxes, 'X')
            ylabel(app.ResultsCurveAxes, 'Y')
            app.ResultsCoordsTable = uitable(app.ResultsSynthesisTab);
            app.ResultsCoordsTable.ColumnName = {'Cx_ach'; 'Cy_ach'};
            app.ResultsCoordsTable.RowName = {};
            app.ResultsCoordsTable.ColumnEditable = [true true];
            app.ResultsCoordsLabel = uilabel(app.ResultsSynthesisTab);
            app.ResultsCoordsLabel.Text = 'Achieved path:';
            app.ResultsClearCoordsButton = uibutton(app.ResultsSynthesisTab, 'push');
            app.ResultsClearCoordsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsClearCoordsButtonPushed, true);
            app.ResultsClearCoordsButton.Tooltip = {'Clear the table with the achieved coordinates'};
            app.ResultsClearCoordsButton.Text = 'Clear';
            app.ResultsKinematicsTab = uitab(app.ResultsTabGroup);
            app.ResultsKinematicsTab.Title = 'Analysis';
            app.ResultsKinSimAxes = uiaxes(app.ResultsKinematicsTab);
            title(app.ResultsKinSimAxes, 'Simulation')
            xlabel(app.ResultsKinSimAxes, 'X')
            ylabel(app.ResultsKinSimAxes, 'Y')
            app.ResultsKinStartButton = uibutton(app.ResultsKinematicsTab, 'push');
            app.ResultsKinStartButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsKinStartButtonPushed, true);
            app.ResultsKinStartButton.Text = 'Simulate';
            app.ResultsKinAngVelAxes = uiaxes(app.ResultsKinematicsTab);
            title(app.ResultsKinAngVelAxes, 'Angular velocity')
            xlabel(app.ResultsKinAngVelAxes, '\theta_2 (rad)')
            ylabel(app.ResultsKinAngVelAxes, '\omega (rad/s)')
            app.ResultsKinAngAccAxes = uiaxes(app.ResultsKinematicsTab);
            title(app.ResultsKinAngAccAxes, 'Angular acceleration')
            xlabel(app.ResultsKinAngAccAxes, '\theta_2 (rad)')
            ylabel(app.ResultsKinAngAccAxes, '\alpha (rad/s^2)')
            app.th2IniEditFieldLabel = uilabel(app.ResultsKinematicsTab);
            app.th2IniEditFieldLabel.Text = 'th2Ini';
            app.Resultsth2IniEditField = uieditfield(app.ResultsKinematicsTab, 'numeric');
            app.Resultsth2IniEditField.Limits = [0 720];
            app.Resultsth2IniEditField.Tooltip = {'Initial crank angle at the beginning of the simulation (in radians)'};
            app.th2EndEditFieldLabel = uilabel(app.ResultsKinematicsTab);
            app.th2EndEditFieldLabel.Text = 'th2End';
            app.Resultsth2EndEditField = uieditfield(app.ResultsKinematicsTab, 'numeric');
            app.Resultsth2EndEditField.Limits = [0 720];
            app.Resultsth2EndEditField.Tooltip = {'Final crank angle at the end of the simulation (in radians)'};
            app.stepEditFieldLabel = uilabel(app.ResultsKinematicsTab);
            app.stepEditFieldLabel.Text = 'step';
            app.ResultsstepEditField = uieditfield(app.ResultsKinematicsTab, 'numeric');
            app.ResultsstepEditField.Limits = [-360 360];
            app.ResultsstepEditField.Tooltip = {'Difference between the crank angle in each time step of the simulation (radians, either positive or negative)'};
            app.dTh2EditFieldLabel = uilabel(app.ResultsKinematicsTab);
            app.dTh2EditFieldLabel.Text = 'dTh2';
            app.ResultsdTh2EditField = uieditfield(app.ResultsKinematicsTab, 'numeric');
            app.ResultsdTh2EditField.Limits = [-360 360];
            app.ResultsdTh2EditField.Tooltip = {'Cank angular velocity (rad/s)'};
            app.ResultsCalcVelAccCheckBox = uicheckbox(app.ResultsKinematicsTab);
            app.ResultsCalcVelAccCheckBox.Tooltip = {'Compute angular velocity and acceleration'};
            app.ResultsCalcVelAccCheckBox.Text = 'Vel. & Acc.';
            app.ResultsAnglesinradsLabel = uilabel(app.ResultsKinematicsTab);
            app.ResultsAnglesinradsLabel.Text = '(Angles in radians)';
            app.ResultsLinkageparametersLabel = uilabel(app.ResultsPanel);
            app.ResultsLinkageparametersLabel.Text = 'Linkage parameters (angles in radians):';
            app.ResultsLinkageTable = uitable(app.ResultsPanel);
            app.ResultsLinkageTable.ColumnName = {'Lb'; 'Ub'; 'Value'};
            app.ResultsLinkageTable.RowName = {'r1'; 'r2'; 'r3'; 'r4'; 'rcx'; 'rcy'; 'x0'; 'y0'; 'th0'; 'th20'};
            app.ResultsLinkageTable.ColumnEditable = [true true true];
            app.LogTextAreaLabel = uilabel(app.ResultsPanel);
            app.LogTextAreaLabel.Text = 'Log:';
            app.ResultsLogTextArea = uitextarea(app.ResultsPanel);
            app.ResultsResetButton = uibutton(app.ResultsPanel, 'push');
            app.ResultsResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsResetButtonPushed, true);
            app.ResultsResetButton.Tooltip = {'Clear the current result'};
            app.ResultsResetButton.Text = 'Reset';
            app.ResultsPlotButton = uibutton(app.ResultsPanel, 'push');
            app.ResultsPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsPlotButtonPushed, true);
            app.ResultsPlotButton.Tooltip = {'Plot the current result'};
            app.ResultsPlotButton.Text = 'Plot';
            app.AbsoluteerrorEditFieldLabel_2 = uilabel(app.ResultsPanel);
            app.AbsoluteerrorEditFieldLabel_2.Text = 'Absolute error:';
            app.ResultsAbsolutRerrorEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.ResultsAbsolutRerrorEditField.Value = -Inf;
            app.TDerrorLabel = uilabel(app.ResultsPanel);
            app.TDerrorLabel.Text = 'TD error:';
            app.ResultsTDerrorEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.ResultsTDerrorEditField.Value = -Inf;
            app.NSDVerrorLabel = uilabel(app.ResultsPanel);
            app.NSDVerrorLabel.Text = 'NSDV error:';
            app.ResultsNSDVerrorEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.ResultsNSDVerrorEditField.Value = -Inf;
            % Show the figure after all components are created
            app.SALARMechanismsynthesizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MechanismSynth

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SALARMechanismsynthesizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SALARMechanismsynthesizerUIFigure)
        end
    end
end